classdef QuailKit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuailkitUIFigure   matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        ImportMenu         matlab.ui.container.Menu
        SettingsMenu       matlab.ui.container.Menu
        GridLayout2        matlab.ui.container.GridLayout
        SpectrogramsPanel  matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        Button             matlab.ui.control.Button
        Button_2           matlab.ui.control.Button
        Button_3           matlab.ui.control.Button
        Button_4           matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_4           matlab.ui.control.UIAxes
        Panel              matlab.ui.container.Panel
        ModeSwitchLabel    matlab.ui.control.Label
        ModeSwitch         matlab.ui.control.Switch
        MapSwitchLabel     matlab.ui.control.Label
        MapSwitch          matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function OnlineButtonPushed(app, event)
            
        end

        % Size changed function: SpectrogramsPanel
        function SpectrogramsPanelSizeChanged(app, event)
            position = app.SpectrogramsPanel.Position;
        end

        % Callback function
        function MapCheckBoxValueChanged(app, event)
          
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
        end

        % Value changed function: MapSwitch
        function MapSwitchValueChanged(app, event)
            if strcmp(app.ModeSwitch.Value,"Online")
                if strcmp(app.MapSwitch.Value,'On')
                    x= 1:0.01:4*pi;
                    gx = geoaxes('parent',app.Panel)
                    geoplot(gx,33.35,-101.012,'o','MarkerSize',5,'MarkerFaceColor','b');
                    geobasemap(gx, 'satellite')
                else
                    delete(app.Panel.Children)
                end
             end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            value = app.ModeSwitch.Value;
            if strcmp(app.ModeSwitch.Value,"Online")
                t = 0:0.001:2;
                x = chirp(t,100,1,500,'quadratic');
                [f ,t,p] = spectrogram(x,100,80,100,1000,'xaxis');
                imagesc(app.UIAxes,abs(f))
                imagesc(app.UIAxes_2,abs(f))
                imagesc(app.UIAxes_3,abs(f))
                imagesc(app.UIAxes_4,abs(f))
                xlim(app.UIAxes,[10 20])
            else
                delete(app.Panel.Children)
                for i = 5:8
                    cla(app.SpectrogramsPanel.Children.Children(i))
                end
                app.MapSwitch.Value = 'Off';
            end
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            disp(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuailkitUIFigure = uifigure('Visible', 'off');
            app.QuailkitUIFigure.Name = 'Quailkit';
            app.FileMenu = uimenu(app.QuailkitUIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.SettingsMenu = uimenu(app.FileMenu);
            app.SettingsMenu.Text = 'Settings';
            app.GridLayout2 = uigridlayout(app.QuailkitUIFigure);
            app.SpectrogramsPanel = uipanel(app.GridLayout2);
            app.SpectrogramsPanel.Title = 'Spectrograms';
            app.SpectrogramsPanel.SizeChangedFcn = createCallbackFcn(app, @SpectrogramsPanelSizeChanged, true);
            app.GridLayout = uigridlayout(app.SpectrogramsPanel);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'play.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.Icon = 'play.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.Icon = 'play.png';
            app.Button_4.Text = '';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Frequency')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, 'Frequency')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, 'Frequency')
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'Time')
            ylabel(app.UIAxes_4, 'Frequency')
            app.Panel = uipanel(app.GridLayout2);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.ModeSwitchLabel = uilabel(app.GridLayout2);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ModeSwitch.Items = {'Offline', 'Online'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Offline';
            app.MapSwitchLabel = uilabel(app.GridLayout2);
            app.MapSwitchLabel.Text = 'Map';
            app.MapSwitch = uiswitch(app.GridLayout2, 'slider');
            app.MapSwitch.ValueChangedFcn = createCallbackFcn(app, @MapSwitchValueChanged, true);
            % Show the figure after all components are created
            app.QuailkitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuailKit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuailkitUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuailkitUIFigure)
        end
    end
end